classdef about < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AboutUIFigure                  matlab.ui.Figure
        Button_10                      matlab.ui.control.Button
        Button_12                      matlab.ui.control.Button
        DeveloperLabel_2               matlab.ui.control.Label
        CsarHueteLabel                 matlab.ui.control.Label
        Button_11                      matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        MarcosVeraLabel                matlab.ui.control.Label
        DeveloperLabel                 matlab.ui.control.Label
        Button_4                       matlab.ui.control.Button
        RepositoryButton               matlab.ui.control.Button
        ContributorsButton             matlab.ui.control.Button
        WikiButton                     matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        Button_9                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        MaindeveloperAppDesignerLabel  matlab.ui.control.Label
        AlbertoCuadraLaraLabel         matlab.ui.control.Label
        Button_5                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             %%%% Resize app
%             screenSize = get(groot,'ScreenSize');
%             screenWidth = screenSize(3);
%             screenHeight = screenSize(4);
%             left = screenWidth*0.3;
%             bottom = screenHeight*0.25;
%             width = 600;
%             height = 434;
%             app.AboutUIFigure.Position = [left bottom width height];
%             %%%% Center app
%             movegui(app.AboutUIFigure,'center');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            web('http://fluidosuc3m.es/','-browser');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            web('https://www.linkedin.com/in/albertocuadralara/','-browser');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            web('https://www.linkedin.com/in/marcos-vera-coello-05b3a643/?originalSubdomain=es','-browser');
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            web('mailto:acuadra@ing.uc3m.es');
        end

        % Callback function
        function Button_9Pushed(app, event)
            
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             web('https://www.researchgate.net/profile/Marcos_Vera','-browser');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            web('https://www.researchgate.net/profile/Alberto_Cuadra_Lara','-browser');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web('https://acuadralara.com/','-browser');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            web('http://fluidosuc3m.es/people/mvcoello/','-browser');
        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
            
        end

        % Button pushed function: RepositoryButton
        function RepositoryButtonPushed(app, event)
            web('https://github.com/AlbertoCuadra/combustion_toolbox','-browser');
        end

        % Button pushed function: ContributorsButton
        function ContributorsButtonPushed(app, event)
            web('https://github.com/AlbertoCuadra/combustion_toolbox/blob/master/CONTRIBUTORS.md','-browser');
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            web('https://www.researchgate.net/profile/Cesar-Huete','-browser');
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            web('https://www.linkedin.com/in/cesarhuete/','-browser');
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            web('http://fluidosuc3m.es/people/chuete/','-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AboutUIFigure = uifigure('Visible', 'off');
            app.AboutUIFigure.Name = 'About';
            app.Button = uibutton(app.AboutUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'foto.jpg';
            app.Button.Text = '';
            app.Button_5 = uibutton(app.AboutUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'fluids_uc3m_icon.svg';
            app.Button_5.Text = '';
            app.AlbertoCuadraLaraLabel = uilabel(app.AboutUIFigure);
            app.AlbertoCuadraLaraLabel.Text = 'Alberto Cuadra-Lara';
            app.MaindeveloperAppDesignerLabel = uilabel(app.AboutUIFigure);
            app.MaindeveloperAppDesignerLabel.Text = 'Main developer & App Designer';
            app.Button_6 = uibutton(app.AboutUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'mail_icon.svg';
            app.Button_6.Text = '';
            app.Button_9 = uibutton(app.AboutUIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Icon = 'github_icon.svg';
            app.Button_9.Text = '';
            app.Button_8 = uibutton(app.AboutUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'researchgate_icon.svg';
            app.Button_8.Text = '';
            app.Button_2 = uibutton(app.AboutUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'linkedin_icon.svg';
            app.Button_2.Text = '';
            app.WikiButton = uibutton(app.AboutUIFigure, 'push');
            app.WikiButton.Text = 'Wiki';
            app.ContributorsButton = uibutton(app.AboutUIFigure, 'push');
            app.ContributorsButton.ButtonPushedFcn = createCallbackFcn(app, @ContributorsButtonPushed, true);
            app.ContributorsButton.Text = 'Contributors';
            app.RepositoryButton = uibutton(app.AboutUIFigure, 'push');
            app.RepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @RepositoryButtonPushed, true);
            app.RepositoryButton.Text = 'Repository';
            app.Button_4 = uibutton(app.AboutUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'mvcoello240x360.jpg';
            app.Button_4.Text = '';
            app.DeveloperLabel = uilabel(app.AboutUIFigure);
            app.DeveloperLabel.Text = 'Developer';
            app.MarcosVeraLabel = uilabel(app.AboutUIFigure);
            app.MarcosVeraLabel.Text = 'Marcos Vera';
            app.Button_7 = uibutton(app.AboutUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'researchgate_icon.svg';
            app.Button_7.Text = '';
            app.Button_3 = uibutton(app.AboutUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'linkedin_icon.svg';
            app.Button_3.Text = '';
            app.Button_11 = uibutton(app.AboutUIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Icon = 'chuete.png';
            app.Button_11.Text = '';
            app.CsarHueteLabel = uilabel(app.AboutUIFigure);
            app.CsarHueteLabel.Text = 'César Huete';
            app.DeveloperLabel_2 = uilabel(app.AboutUIFigure);
            app.DeveloperLabel_2.Text = 'Developer';
            app.Button_12 = uibutton(app.AboutUIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Icon = 'researchgate_icon.svg';
            app.Button_12.Text = '';
            app.Button_10 = uibutton(app.AboutUIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Icon = 'linkedin_icon.svg';
            app.Button_10.Text = '';
            % Show the figure after all components are created
            app.AboutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = about

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AboutUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AboutUIFigure)
        end
    end
end