classdef uivalidations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ValidationsUIFigure  matlab.ui.Figure
        CloseButton          matlab.ui.control.Button
        RunButton            matlab.ui.control.Button
        Tree                 matlab.ui.container.Tree
        CEA                  matlab.ui.container.TreeNode
        SDToolbox            matlab.ui.container.TreeNode
        CANTERA              matlab.ui.container.TreeNode
        TEA                  matlab.ui.container.TreeNode
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Include validations in the UITree object
            gui_add_nodes_validations(app, 'CEA');
            gui_add_nodes_validations(app, 'SDToolbox');
            gui_add_nodes_validations(app, 'CANTERA');
            gui_add_nodes_validations(app, 'TEA');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                run(app.Tree.SelectedNodes.Text)
            catch ME
                % Print error
                message = {sprintf('Error in function %s() at line %d.\n\nError Message:\n%s', ...
                ME.stack(1).name, ME.stack(1).line, ME.message)};
                uialert(obj.ValidationsUIFigure, message, 'Warning', 'Icon', 'warning');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ValidationsUIFigure = uifigure('Visible', 'off');
            app.ValidationsUIFigure.Name = 'UIValidations';
            app.ValidationsUIFigure.Icon = 'validation.png';
            app.Tree = uitree(app.ValidationsUIFigure);
            app.CEA = uitreenode(app.Tree);
            app.CEA.Text = 'CEA';
            app.SDToolbox = uitreenode(app.Tree);
            app.SDToolbox.Text = 'SDToolbox';
            app.CANTERA = uitreenode(app.Tree);
            app.CANTERA.Text = 'CANTERA';
            app.TEA = uitreenode(app.Tree);
            app.TEA.Text = 'TEA';
            app.RunButton = uibutton(app.ValidationsUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CloseButton = uibutton(app.ValidationsUIFigure, 'push');
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.ValidationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uivalidations

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ValidationsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ValidationsUIFigure)
        end
    end
end